classdef MFAgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MFAUIFigure                     matlab.ui.Figure
        UpdateDatabaseButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        GROUPListBoxLabel               matlab.ui.control.Label
        GROUPListBox                    matlab.ui.control.ListBox
        SIGNALListBoxLabel              matlab.ui.control.Label
        SIGNALListBox                   matlab.ui.control.ListBox
        ZeroShiftTimeCheckBox           matlab.ui.control.CheckBox
        PlotFcnList                     matlab.ui.control.DropDown
        PlotFunctionButton              matlab.ui.control.Button
        ZeroMeanCheckBox                matlab.ui.control.CheckBox
        PlotNewButton                   matlab.ui.control.Button
        PlotCurrentButton               matlab.ui.control.Button
        HoldPlotCheckBox                matlab.ui.control.CheckBox
        FRETab                          matlab.ui.container.Tab
        INPUTSPanel                     matlab.ui.container.Panel
        PRILabel                        matlab.ui.control.Label
        PRIinp                          matlab.ui.control.ListBox
        SECLabel                        matlab.ui.control.Label
        SECinp                          matlab.ui.control.ListBox
        OUTPUTSPanel                    matlab.ui.container.Panel
        selOut                          matlab.ui.control.ListBox
        WINDOWSPanel                    matlab.ui.container.Panel
        AEditFieldLabel                 matlab.ui.control.Label
        winA                            matlab.ui.control.NumericEditField
        MinFreqLabel                    matlab.ui.control.Label
        minFreqB                        matlab.ui.control.NumericEditField
        BLabel                          matlab.ui.control.Label
        winB                            matlab.ui.control.NumericEditField
        minFreqC                        matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        winC                            matlab.ui.control.NumericEditField
        minFreqD                        matlab.ui.control.NumericEditField
        DLabel                          matlab.ui.control.Label
        winD                            matlab.ui.control.NumericEditField
        minFreqE                        matlab.ui.control.NumericEditField
        ELabel                          matlab.ui.control.Label
        winE                            matlab.ui.control.NumericEditField
        winFreqUnit                     matlab.ui.control.DropDown
        FreqUnitsLabel                  matlab.ui.control.Label
        AutoMinFreqButton               matlab.ui.control.Button
        MaxFreqEditFieldLabel           matlab.ui.control.Label
        maxWinFreq                      matlab.ui.control.NumericEditField
        minFreqA                        matlab.ui.control.NumericEditField
        secLabel                        matlab.ui.control.Label
        XcorrCtrlCheckBox               matlab.ui.control.CheckBox
        ProcessButton                   matlab.ui.control.Button
        FREDropDownLabel                matlab.ui.control.Label
        FREDropDown                     matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        statusWin                       matlab.ui.control.TextArea
        OptionsPanel                    matlab.ui.container.Panel
        DataSampleRateHzEditFieldLabel  matlab.ui.control.Label
        DataSampleRateHzEditField       matlab.ui.control.NumericEditField
        FilterCutoffEditFieldLabel      matlab.ui.control.Label
        FilterCutoffEditField           matlab.ui.control.NumericEditField
        filtFreqUnit                    matlab.ui.control.DropDown
        LOGDropDownLabel                matlab.ui.control.Label
        LOGDropDown                     matlab.ui.control.DropDown
        SweepRangeDropDownLabel         matlab.ui.control.Label
        SweepRangeDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        mfa MFlightAnalysis; % database
        activeLogIdx = 1;
        activeSweep;
        sweepTimes;
    end
    
    methods (Access = private)
        
        function updateMFAData(app)
            % log drop down
            app.updateLogList
            app.updateGroupList
            app.updateSignalList
            app.updateSweepTimes
            % update plot list
            mc = metaclass(app.mfa);
            mlist = arrayfun(@(x) x.Name, mc.MethodList, 'UniformOutput', false);
            mlist(~contains(mlist,'plot_')) = [];
            mlist = cellfun(@(x) strrep(x,'plot_',''), mlist, 'UniformOutput', false);
            app.PlotFcnList.Items = mlist;
        end
        function updateLogList(app)
            logNum = app.mfa.loadedLogs;
            logStr = arrayfun(@(x) num2str(x), logNum,'UniformOutput', false);
            % for each log, get the active axis
            for j=1:numel(app.mfa.logs)
                try
                    tmp = app.mfa.getSignal(j, 'SWP1', 'sax');
                    switch mean(tmp.sax)
                        case 1
                            str = 'ROLL';
                        case 2
                            str = 'PITCH';
                        case 3
                            str = 'YAW';
                        case 4
                            str = 'COL';
                        otherwise
                            str = '';
                    end
                    logStr{j} = sprintf('%s, %s',logStr{j}, str);
                end
            end
            app.LOGDropDown.Items = logStr;
        end
        function mode = getActiveMode(app, time)
            msig = app.mfa.getSignal(app.activeLogIdx, 'MODE', 'Mode');
            mval = msig.Mode(max(find(msig.time < time)));
            switch mval
                case 0
                    mode = 'stab';
                case 1
                    mode = 'acro';
                case 16
                    mode = 'phld';
                otherwise
                    mode = '';
            end
        end
        function updateSweepTimes(app)
            try
                [~, app.sweepTimes] = app.mfa.getSweepIndices('logIndex', app.activeLogIdx);
                swps = arrayfun(@(x) sprintf('%d',x), 1:size(app.sweepTimes, 1), 'UniformOutput', false);
                sflg = app.mfa.getSignal(app.activeLogIdx, 'SWP1', 'sflg');
                for j=1:numel(swps)
                    idx = find(sflg.time >= app.sweepTimes(j,1), 1);
                    tmpTime = sflg.time(idx:end);
                    time = min(tmpTime( find(sflg.sflg(idx:end) == 1) ) );
                    mstr = app.getActiveMode(time);
                    swps{j} = sprintf('%s, %s', swps{j}, mstr);
                end
                opt = horzcat({'-'},swps);
            catch
                opt = {'-'};
            end
            app.SweepRangeDropDown.Items = opt;
        end
        function updateGroupList(app)
            grps = sort(app.mfa.logs{app.activeLogIdx}.log.msgsContained);
            % filter out empty groups
            validGroups = cellfun(@(x) ~isempty(app.mfa.logs{app.activeLogIdx}.log.(x).TimeS), grps);
            app.GROUPListBox.Items = grps(validGroups);
        end
        function updateLogIdx(app)
            app.activeLogIdx = app.mfa.getLogIdx(app.LOGDropDown.Value);
        end
        function updateSignalList(app)
            excludeList = {'LineNo', 'TimeS', 'DatenumUTC', 'TimeUS'};    
            group   = app.GROUPListBox.Value;
            grp     = app.mfa.logs{app.activeLogIdx}.log.(group);
            fn      = fieldnames(grp);
            fn(cellfun(@(x) any(strcmp(x, excludeList)), fn)) = [];
            keep    = false(size(fn));
            tLength = length(grp.TimeS);
            for j=1:numel(fn)
                if isnumeric(grp.(fn{j})) && length(grp.(fn{j})) == tLength
                    keep(j) = true;
                end
            end
            app.SIGNALListBox.Items = sort(fn(keep));
        end
        function plotSignal(app, group, signal)
            sr = app.activeSweep;
            tmp = app.mfa.getSignal( app.activeLogIdx, group, signal);
            if ~strcmp(sr, '-')
               srn = str2double(sr);
               iStart = find(tmp.time <= app.sweepTimes(srn, 1), 1, 'last');
               iStop  = find(tmp.time >= app.sweepTimes(srn, 2), 1, 'first');
               tmp.time = tmp.time(iStart:iStop);
               tmp.(signal) = tmp.(signal)(iStart:iStop);
            end
            if (app.ZeroShiftTimeCheckBox.Value)
                tmp.time = tmp.time - tmp.time(1);
            end
            if (app.ZeroMeanCheckBox.Value)
                tmp.(signal) = tmp.(signal) - mean(tmp.(signal));
            end
            plot(tmp.time, tmp.(signal))
            ylabel(tmp.unit)
            xlabel('Time (s)')
            title(signal)
            grid on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            baseVars = evalin('base','whos');
            baseMFA  = baseVars(arrayfun(@(x) strcmp(x.class, 'MFlightAnalysis'), baseVars));
            if isempty(baseMFA)
                error('no base vars of correct type')
            elseif numel(baseMFA) > 1
                error('more than one instance')
            end
            app.mfa = evalin('base',baseMFA.name);
            app.updateMFAData
        end

        % Value changed function: GROUPListBox
        function GROUPListBoxValueChanged(app, event)
            app.updateSignalList
        end

        % Value changed function: LOGDropDown
        function LOGDropDownValueChanged(app, event)
            value = app.LOGDropDown.Value;
            tmp = regexp(value, ',','split');
            app.activeLogIdx = app.mfa.getLogIdx(str2double(tmp{1}));
            app.updateSweepTimes
            app.updateGroupList
            app.updateSignalList
           
        end

        % Button pushed function: PlotNewButton
        function PlotNewButtonPushed(app, event)
            figure
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
            if app.HoldPlotCheckBox.Value
                hold on
            end
        end

        % Button pushed function: PlotCurrentButton
        function PlotCurrentButtonPushed(app, event)
            if app.HoldPlotCheckBox.Value
                hold(gca,'on');
            else
                hold(gca,'off');
            end
            
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
        end

        % Value changed function: SweepRangeDropDown
        function SweepRangeDropDownValueChanged(app, event)
            value = app.SweepRangeDropDown.Value;
            tmp = regexp(value, ',', 'split');
            app.activeSweep = tmp{1};
        end

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
            if ~strcmp(app.activeSweep,'-')
                app.mfa.plot(app.PlotFcnList.Value, 'logNum', app.mfa.logs{app.activeLogIdx}.num,...
                                                    'swpEvent', str2double(app.activeSweep) )
            else
                app.mfa.plot(app.PlotFcnList.Value, 'logNum', app.mfa.logs{app.activeLogIdx}.num)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MFAUIFigure = uifigure('Visible', 'off');
            app.MFAUIFigure.Name = 'MFA';
            app.UpdateDatabaseButton = uibutton(app.MFAUIFigure, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Text = {'Update '; 'Database'};
            app.TabGroup = uitabgroup(app.MFAUIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GROUPListBoxLabel = uilabel(app.PlotTab);
            app.GROUPListBoxLabel.Text = 'GROUP';
            app.GROUPListBox = uilistbox(app.PlotTab);
            app.GROUPListBox.ValueChangedFcn = createCallbackFcn(app, @GROUPListBoxValueChanged, true);
            app.SIGNALListBoxLabel = uilabel(app.PlotTab);
            app.SIGNALListBoxLabel.Text = 'SIGNAL';
            app.SIGNALListBox = uilistbox(app.PlotTab);
            app.ZeroShiftTimeCheckBox = uicheckbox(app.PlotTab);
            app.ZeroShiftTimeCheckBox.Text = 'Zero Shift Time';
            app.ZeroShiftTimeCheckBox.Value = true;
            app.PlotFcnList = uidropdown(app.PlotTab);
            app.PlotFunctionButton = uibutton(app.PlotTab, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.ZeroMeanCheckBox = uicheckbox(app.PlotTab);
            app.ZeroMeanCheckBox.Text = 'ZeroMean';
            app.PlotNewButton = uibutton(app.PlotTab, 'push');
            app.PlotNewButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNewButtonPushed, true);
            app.PlotNewButton.Text = 'Plot New';
            app.PlotCurrentButton = uibutton(app.PlotTab, 'push');
            app.PlotCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentButtonPushed, true);
            app.PlotCurrentButton.Text = 'Plot Current';
            app.HoldPlotCheckBox = uicheckbox(app.PlotTab);
            app.HoldPlotCheckBox.Text = 'Hold Plot';
            app.FRETab = uitab(app.TabGroup);
            app.FRETab.Title = 'FRE';
            app.INPUTSPanel = uipanel(app.FRETab);
            app.INPUTSPanel.Title = 'INPUTS';
            app.PRILabel = uilabel(app.INPUTSPanel);
            app.PRILabel.Text = 'PRI:';
            app.PRIinp = uilistbox(app.INPUTSPanel);
            app.SECLabel = uilabel(app.INPUTSPanel);
            app.SECLabel.Text = 'SEC:';
            app.SECinp = uilistbox(app.INPUTSPanel);
            app.OUTPUTSPanel = uipanel(app.FRETab);
            app.OUTPUTSPanel.Title = 'OUTPUTS';
            app.selOut = uilistbox(app.OUTPUTSPanel);
            app.WINDOWSPanel = uipanel(app.FRETab);
            app.WINDOWSPanel.Title = 'WINDOWS';
            app.AEditFieldLabel = uilabel(app.WINDOWSPanel);
            app.AEditFieldLabel.Text = 'A';
            app.winA = uieditfield(app.WINDOWSPanel, 'numeric');
            app.winA.Value = 45;
            app.minFreqA = uieditfield(app.WINDOWSPanel, 'numeric');
            app.MinFreqLabel = uilabel(app.WINDOWSPanel);
            app.MinFreqLabel.Text = 'Min Freq';
            app.minFreqB = uieditfield(app.WINDOWSPanel, 'numeric');
            app.BLabel = uilabel(app.WINDOWSPanel);
            app.BLabel.Text = 'B';
            app.winB = uieditfield(app.WINDOWSPanel, 'numeric');
            app.winB.Value = 35;
            app.minFreqC = uieditfield(app.WINDOWSPanel, 'numeric');
            app.CLabel = uilabel(app.WINDOWSPanel);
            app.CLabel.Text = 'C';
            app.winC = uieditfield(app.WINDOWSPanel, 'numeric');
            app.winC.Value = 25;
            app.minFreqD = uieditfield(app.WINDOWSPanel, 'numeric');
            app.DLabel = uilabel(app.WINDOWSPanel);
            app.DLabel.Text = 'D';
            app.winD = uieditfield(app.WINDOWSPanel, 'numeric');
            app.winD.Value = 20;
            app.minFreqE = uieditfield(app.WINDOWSPanel, 'numeric');
            app.ELabel = uilabel(app.WINDOWSPanel);
            app.ELabel.Text = 'E';
            app.winE = uieditfield(app.WINDOWSPanel, 'numeric');
            app.winE.Value = 15;
            app.winFreqUnit = uidropdown(app.WINDOWSPanel);
            app.winFreqUnit.Items = {'Hz', 'rad/s'};
            app.winFreqUnit.Value = 'rad/s';
            app.FreqUnitsLabel = uilabel(app.WINDOWSPanel);
            app.FreqUnitsLabel.Text = 'Freq Units:';
            app.AutoMinFreqButton = uibutton(app.WINDOWSPanel, 'push');
            app.AutoMinFreqButton.Text = 'Auto Min Freq';
            app.MaxFreqEditFieldLabel = uilabel(app.WINDOWSPanel);
            app.MaxFreqEditFieldLabel.Text = 'Max Freq';
            app.maxWinFreq = uieditfield(app.WINDOWSPanel, 'numeric');
            app.maxWinFreq.Value = 30;
            app.secLabel = uilabel(app.WINDOWSPanel);
            app.secLabel.Text = '(sec)';
            app.OptionsPanel = uipanel(app.FRETab);
            app.OptionsPanel.Title = 'Options';
            app.DataSampleRateHzEditFieldLabel = uilabel(app.OptionsPanel);
            app.DataSampleRateHzEditFieldLabel.Text = 'Data Sample Rate (Hz)';
            app.DataSampleRateHzEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DataSampleRateHzEditField.Editable = 'off';
            app.FilterCutoffEditFieldLabel = uilabel(app.OptionsPanel);
            app.FilterCutoffEditFieldLabel.Text = 'Filter Cutoff';
            app.FilterCutoffEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.FilterCutoffEditField.Editable = 'off';
            app.FilterCutoffEditField.Tooltip = {'filter cutoff frequency. should be have common base rate of original sample.'};
            app.filtFreqUnit = uidropdown(app.OptionsPanel);
            app.filtFreqUnit.Items = {'Hz', 'rad/s'};
            app.filtFreqUnit.Value = 'Hz';
            app.XcorrCtrlCheckBox = uicheckbox(app.OptionsPanel);
            app.XcorrCtrlCheckBox.Tooltip = {'remove correlation between control inputs'};
            app.XcorrCtrlCheckBox.Text = 'Xcorr Ctrl';
            app.XcorrCtrlCheckBox.Value = true;
            app.ProcessButton = uibutton(app.FRETab, 'push');
            app.ProcessButton.Text = 'Process';
            app.FREDropDownLabel = uilabel(app.FRETab);
            app.FREDropDownLabel.Text = 'FRE';
            app.FREDropDown = uidropdown(app.FRETab);
            app.PlotButton = uibutton(app.FRETab, 'push');
            app.PlotButton.Text = 'Plot';
            app.statusWin = uitextarea(app.FRETab);
            app.statusWin.Editable = 'off';
            app.LOGDropDownLabel = uilabel(app.MFAUIFigure);
            app.LOGDropDownLabel.Text = 'LOG';
            app.LOGDropDown = uidropdown(app.MFAUIFigure);
            app.LOGDropDown.ValueChangedFcn = createCallbackFcn(app, @LOGDropDownValueChanged, true);
            app.SweepRangeDropDownLabel = uilabel(app.MFAUIFigure);
            app.SweepRangeDropDownLabel.Text = 'Sweep Range';
            app.SweepRangeDropDown = uidropdown(app.MFAUIFigure);
            app.SweepRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @SweepRangeDropDownValueChanged, true);
            % Show the figure after all components are created
            app.MFAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFAgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MFAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MFAUIFigure)
        end
    end
end